classdef VsrLimitBlockUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        MainGridLayout       matlab.ui.container.GridLayout
        ButtonGridLayout     matlab.ui.container.GridLayout
        OKButton             matlab.ui.control.Button
        CancelButton         matlab.ui.control.Button
        ApplyButton          matlab.ui.control.Button
        LimitUITable         matlab.ui.control.Table
        OutputRangeCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Hidden = true)
        BlockName  % Name of block
    end
    
    methods
        
        %% updateBlockDialog
        function updateBlockDialog(app, BlockName)
            MaxNumRows = 15;
            
            app.BlockName = BlockName;
            ValueNames = eval(get_param(BlockName, 'ValueNames'));
            if ~iscell(ValueNames)
                ValueNames = eval(char(ValueNames));
            end
            LowerLimit = app.paramStr2cellstr(get_param(BlockName, 'LowerLimit'));
            LowerWarning = app.paramStr2cellstr(get_param(BlockName, 'LowerWarning'));
            UpperWarning = app.paramStr2cellstr(get_param(BlockName, 'UpperWarning'));
            UpperLimit = app.paramStr2cellstr(get_param(BlockName, 'UpperLimit'));
            MaxNumRows = max(length(UpperLimit), MaxNumRows);
            
            TableData = cell(MaxNumRows, 5);
            TableData(1:length(ValueNames),1) = ValueNames(:);
            TableData(1:length(LowerLimit),2) = LowerLimit(:);
            TableData(1:length(LowerWarning),3) = LowerWarning(:);
            TableData(1:length(UpperWarning),4) = UpperWarning(:);
            TableData(1:length(UpperLimit),5) = UpperLimit(:);

            app.LimitUITable.Data = TableData;
            
            OutputTblCheckboxVal = get_param(BlockName, 'OutputTbls');
            if strcmp(OutputTblCheckboxVal, 'true')
                app.OutputRangeCheckBox.Value = true;
            else
                app.OutputRangeCheckBox.Value = false;
            end
        end
        
        %% updateBlockParams
        function updateBlockParams(app)
            ValueNames = '{';
            LowerLimit = '[';
            LowerWarning = '[';
            UpperWarning = '[';
            UpperLimit = '[';
            
            Idx = 1;
            CurrValueName = app.LimitUITable.Data(Idx, 1);
            while ~isempty(CurrValueName) && Idx < size(app.LimitUITable.Data, 1)
               
                ValueNames = [ValueNames, '''', app.LimitUITable.Data{Idx,1}, ''','];
                LowerLimit = app.addValToParamStr(LowerLimit, Idx, 2);
                LowerWarning = app.addValToParamStr(LowerWarning, Idx, 3);
                UpperWarning = app.addValToParamStr(UpperWarning, Idx, 4);
                UpperLimit = app.addValToParamStr(UpperLimit, Idx, 5);
                
                Idx = Idx + 1;
                if Idx <= size(app.LimitUITable.Data, 1)
                    CurrValueName = app.LimitUITable.Data{Idx, 1};
                end
            end
            
            ValueNames = [ValueNames(1:end-1), '}'];
            ValueNameUint8 = strrep(mat2str(uint8(ValueNames)), ' ', ',');
            LowerLimit = [LowerLimit(1:end-1), ']'];
            LowerWarning = [LowerWarning(1:end-1), ']'];
            UpperWarning = [UpperWarning(1:end-1), ']'];
            UpperLimit = [UpperLimit(1:end-1), ']'];
            
            set_param(app.BlockName, 'ValueNames', ValueNameUint8);
            set_param(app.BlockName, 'LowerLimit', LowerLimit);
            set_param(app.BlockName, 'LowerWarning', LowerWarning);
            set_param(app.BlockName, 'UpperWarning', UpperWarning);
            set_param(app.BlockName, 'UpperLimit', UpperLimit);
            
            if app.OutputRangeCheckBox.Value
                set_param(app.BlockName, 'OutputTbls', 'true')
            else
                set_param(app.BlockName, 'OutputTbls', 'false')
            end
            
            
        end
    end
    
    %% Private methods
    methods (Access = private)
        
        %% paramStr2cellstr
        function out = paramStr2cellstr(app, in)
            OpenStrIdx = findstr(in, '[');
            OpenStrIdx = OpenStrIdx(end);
            EndStrIdx = findstr(in, ']');
            EndStrIdx = EndStrIdx(end);
            
            if OpenStrIdx + 1 >= EndStrIdx
                str = '';
            else
                str = in((OpenStrIdx+1):(EndStrIdx-1)); 
            end
            
            if isempty(str)
                out = {};
            else
                CommaLoc = strfind(str, ';');
                CommaLoc = [CommaLoc, length(str) + 1];
                out = cell(length(CommaLoc), 1);
                StartIdx = 1;
                for i = 1:length(out)
                    NewStr = str(StartIdx:(CommaLoc(i)-1));
                    if strcmpi(NewStr, 'nan')
                        NewStr = '';
                    end
                    out{i} = strrep(NewStr, ' ', '');
                    StartIdx = CommaLoc(i) + 1;
                end
                
            end
        end
        
        %% addValToParamStr
        function out = addValToParamStr(app, in, idx1, idx2)
            if isempty(app.LimitUITable.Data{idx1, idx2})
                out = [in, 'nan;'];
            else
                out = [in, app.LimitUITable.Data{idx1, idx2}, ';'];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.updateBlockParams;
            app.delete;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.updateBlockParams;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.ButtonGridLayout = uigridlayout(app.MainGridLayout);
            app.OKButton = uibutton(app.ButtonGridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.ButtonGridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.ButtonGridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.LimitUITable = uitable(app.MainGridLayout);
            app.LimitUITable.ColumnName = {'Parameter Name'; 'Lower Limit'; 'Lower Warning'; 'Upper Warning'; 'Upper Limit'};
            app.LimitUITable.RowName = {};
            app.LimitUITable.ColumnSortable = [true false false false false];
            app.LimitUITable.ColumnEditable = true;
            app.OutputRangeCheckBox = uicheckbox(app.MainGridLayout);
            app.OutputRangeCheckBox.Text = 'Output ranges and flags for each value input';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VsrLimitBlockUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end